classdef BloodPressure_Delay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label_LowPressure   matlab.ui.control.Label
        Label_8             matlab.ui.control.Label
        Label_HighPressure  matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_SPO2          matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_HR            matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Description
        COMS;
        port;
        port_use;
        s;
        PPG;
        ECG;
        Final_PPG;
        Final_ECG;
        Band_Pass;
    end
    
    methods (Access = private)
        
        function [HeartRating,systolic_BP,diastolic_BP] = Pressure_Work(app)
           % 假设 app.Final_PPG 和 app.Final_ECG 已经在工作区中
            ppg = app.Final_PPG;
            ecg = app.Final_ECG;
            ppg_signal = app.Final_PPG;
            ecg_signal = app.Final_ECG;
            % 估算的原始采样率
            fs_original = 256; 
         
            % 目标采样率
            fs_target = 256;
            
            % 重采样
            ppg_resampled = resample(ppg_signal, fs_target, fs_original);
            ecg_resampled = resample(ecg_signal, fs_target, fs_original);
            
            
            % 设置采样频率（如果不确定，可尝试不同值，假设为1000 Hz）
            fs = fs_target;
            
            
            % 数据预处理：移除非有限值
            ppg_resampled = ppg_resampled(isfinite(ppg_resampled));
            ecg_resampled = ecg_resampled(isfinite(ecg_resampled));
                        
             Fs = fs;
            % 2. 巴特沃斯滤波器设计
            %频带设置
            ws_d = 0.05; %阻带设置
            ws_s = 50;
            wp_d = 2; %通带设置
            wp_s = 30;
            Ws = [ws_d ws_s]*2/Fs;
            Wp = [wp_d wp_s]*2/Fs;
            Ap = 1; %通带衰减
            As = 5; %阻带衰减
        
            Nn = length(ecg_resampled); %采样点数
        
            [N,wn] = buttord(Wp,Ws,Ap,As);
            [b,a] = butter(N,wn,'bandpass');
            [mag, w] = freqz(b,a);
            % 3. 信号滤波
            ecg_filtered = filtfilt(b, a, ecg_resampled);
        
            Nn = length(ppg_resampled); %采样点数
        
            [N,wn] = buttord(Wp,Ws,Ap,As);
            [b,a] = butter(N,wn,'bandpass');
            [mag, w] = freqz(b,a);
            % 3. 信号滤波
            ppg_filtered = filtfilt(b, a, ppg_resampled);
            ppg_filtered = ppg_filtered(129:length(ppg_filtered)-128);
            ecg_filtered = ecg_filtered(128:length(ecg_filtered)-128);
            % figure(2)
            % plot(w*Fs/(2*pi)，20*log10(abs(mag)));
            % title('巴特沃兹带通滤波器幅频响应')
            % xlabel('f/Hz'); ylabel('幅值/dB')
            % axis([0 30 -20 2])
            
            plot(app.UIAxes,ppg_filtered);
            plot(app.UIAxes_2,ecg_filtered);
%             figure;
%             subplot(2, 1, 1);
%             plot((1:length(ecg_filtered)) / fs, ecg_filtered);
%             xlabel('Time (s)');
%             ylabel('Amplitude');
%             legend('ECG Signal');
%             % 绘制脉搏波信号及其峰值
%             subplot(2, 1, 2);
%             plot((1:length(ppg_filtered)) / fs, ppg_filtered);
%             xlabel('Time (s)');
%             ylabel('Amplitude');
%             legend('PPG Signal');
        
            % 使用Pan-Tompkins算法检测ECG信号的R波
            % [ecg_peaks, ecg_locs] = pan_tompkins_detector(ecg_filtered, fs);
            [ecg_locs,ecg_peaks] = Find_Rpeaks_tompkins(ecg_filtered,fs);
            % 使用findpeaks函数检测脉搏波信号的峰值
            [ppg_peaks, ppg_locs] = findpeaks(ppg_filtered, 'MinPeakHeight', 1 * mean(ppg_filtered), 'MinPeakDistance', 0.2 * fs);
            
%             % 计算ECG信号的R波与脉搏波峰值之间的时间差
%             time_diff = (ppg_locs - ecg_locs(1:length(ppg_locs))) / fs;
            
            % 绘制ECG信号及其R峰
            figure;
            subplot(2,1,1);
            plot((1:length(ecg_filtered))/fs, ecg_filtered);
            hold on;
            plot(ecg_locs/fs, ecg_peaks, 'ro');
            title('ECG Signal with R Peaks');
            xlabel('Time (s)');
            ylabel('Amplitude');
            legend('ECG Signal', 'R Peaks');
            
            % 绘制脉搏波信号及其峰
            subplot(2,1,2);
            plot((1:length(ppg_filtered))/fs, ppg_filtered);
            hold on;
            plot(ppg_locs/fs, ppg_peaks, 'ro');
            title('PPG Signal with Peaks');
            xlabel('Time (s)');
            ylabel('Amplitude');
            legend('PPG Signal', 'Peaks');
            
            locs_R = ecg_locs;
            locs_PPG = ppg_locs;
            
            % 计算 PTT
            ptt = [];
            HeartRating = 0;
            for i = 1:length(locs_R)-1
                % 寻找 R 波之后最近的 PPG 波峰
                next_ppg_peak = locs_PPG(find(locs_PPG > locs_R(i), 1, 'first'));
                if ~isempty(next_ppg_peak)
                    ptt(end+1) = (next_ppg_peak - locs_R(i)) / fs_target; % PTT in seconds
                end
                
                HeartRating = HeartRating + (locs_R(i+1)-locs_R(i));
            end
            
            HeartRating = HeartRating / (length(locs_R)-1);
            HeartRating = 60 / (HeartRating  / 128) / 1.542252688 * 1.071428
            % 计算平均 PTT
            mean_ptt = mean(ptt)
            disp(mean_ptt);
            % 血压计算（需要定标 constants: BP0 和 k）
            BP0 = 120; % 需要通过校准实验确定
            k = -20; % 需要通过校准实验确定
            
%             systolic_BP = BP0 + k * mean_ptt % 收缩压（高压）
%             diastolic_BP = BP0 - k * mean_ptt % 舒张压（低压）
%             
%             diastolic_BP = 8.477495257019728 + mean_ptt + 64.075943667230660;
%             systolic_BP = 1.683340523281520 + mean_ptt + 1.197008693116749e+02;
            
            % 血压计算（使用线性回归系数）
%             a_sys = 121.5;
%             b_sys = 0.5;
%             a_dia = 76.5;
%             b_dia = -0.5;
%             
%             a_sys = 135.5206;
%             b_sys = -244.7304;
%             a_dia = 79.2330;
%             b_dia = -64.3968;
            
%             a_sys = 131.0996;
%             b_sys = -204.1502;
%             a_dia = 75.6123;
%             b_dia = -31.1650;
            
%             a_sys = 131.6545;
%             b_sys = 207.0660;
%             a_dia = 75.4494;
%             b_dia = -28.4111;
            
%             a_sys = 89.0247;
%             b_sys = 551.6052;
%             a_dia = 44.2128;
%             b_dia = 527.2016;
%             
%             systolic_BP = a_sys + b_sys * mean_ptt; % 收缩压（高压）
%             diastolic_BP = a_dia + b_dia * mean_ptt; % 舒张压（低压）
            
            
%             a_sys = 163.0152;
%             b_sys = -380.5608;
%             a_dia = 72.8879;
%             b_dia = 31.4297;
%             
%             systolic_BP = a_sys + b_sys * mean_ptt; % 收缩压（高压）
%             diastolic_BP = a_dia + b_dia * mean_ptt; % 舒张压（低压）
            
%             a_sys = 192.7926;
%             b_sys = -710.1207;
%             a_dia = 82.1216;
%             b_dia = -43.2058;
%             
%             systolic_BP = a_sys + b_sys * mean_ptt; % 收缩压（高压）
%             diastolic_BP = a_dia + b_dia * mean_ptt; % 舒张压（低压）

%             a_sys = 205.7448;
%             b_sys = -843.6723;
%             a_dia = 98.0204;
%             b_dia = -212.0948;
%             
%             systolic_BP = a_sys + b_sys * mean_ptt; % 收缩压（高压）
%             diastolic_BP = a_dia + b_dia * mean_ptt; % 舒张压（低压）


%             systolic_BP = 0.9835 * (mean_ptt - HeartRating * 0.07) + 120.4465; % 收缩压（高压）
%             diastolic_BP = -6.3437 * (mean_ptt - HeartRating * 0.07) + 37.9177; % 舒张压（低压）
%             HeartRating = 65;
%             systolic_BP = -3.1724 * (mean_ptt - HeartRating * 0.07) + 97.0907;
%             diastolic_BP = -5.7486 * (mean_ptt - HeartRating * 0.07) + 39.6791;
%             systolic_BP = -2.6527 * (mean_ptt - HeartRating * 0.07) + 99.5934
%             diastolic_BP = -5.1802 * (mean_ptt - HeartRating * 0.07) + 42.4544
            systolic_BP = 2.7069 * (mean_ptt - HeartRating * 0.07) + 133.4919
            diastolic_BP = -0.2150 * (mean_ptt - HeartRating * 0.07) + 73.6671

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset;
            [app.COMS,app.port] = Get_COM_Ports();
            app.DropDown.Items = app.COMS;
            app.Lamp.Color = 'r';
            
            app.port_use = app.port(1);
            app.s = serial(app.port_use,'BaudRate',115200,'Terminator','LF');
            
            %% 带通滤波器设计
            % 通带区间[0.5,45]Hz
            Fs = 256;
            fp1 = 0.5;                                                                  % 通带下限频率
            fp2 = 45;                                                                   % 通带上限频率
            fs1 = 0.1;                                                                  % 阻带下限频率
            fs2 = 50;                                                                  % 阻带上限频率
            % 归一化频率 (0 到 1 之间)
            wp1 = fp1/(Fs/2);                                                           % 归一化通带下限频率
            wp2 = fp2/(Fs/2);                                                           % 归一化通带上限频率
            ws1 = fs1/(Fs/2);                                                           % 归一化阻带下限频率
            ws2 = fs2/(Fs/2);                                                           % 归一化阻带上限频率
            % 设定通带纹波和阻带衰减
            delta_p = 0.01;                                                             % 通带纹波
            delta_s = 0.001;                                                            % 阻带衰减
            % 转换为 dB
            A = -20*log10(delta_s);                                                    
            % 过渡带宽度估计
            df = min(wp1 - ws1, ws2 - wp2);   
            % 滤波器阶数估计
            N = ceil((A - 8) / (2.285 * df));                                           
            % 使用fir1函数设计滤波器，'bandpass'选项指定带通滤波器
            % N + 1 确保数目为 N + 1 个滤波器的系数
            b_bandpass = fir1(N, [wp1, wp2], hamming(N + 1), 'bandpass');              
            % 显示FIR滤波器的幅频特性曲线
%             fvtool(b_bandpass, 1, 'Analysis', 'freq');
            app.Band_Pass=b_bandpass;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [app.COMS,app.port] = Get_COM_Ports();
            app.DropDown.Items = app.COMS;
            
            disp(length(app.port));
            disp(app.port);
            disp(app.DropDown.Items);
            disp(length(app.DropDown.Items))
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if strcmp(get(app.s,'Status'),'closed')
                fopen(app.s);
                if strcmp(get(app.s,'Status'),'open')
                    app.Lamp.Color = 'g';
                    
                    app.Button_2.Text = "关闭串口";
                    Data_Count = 0;
                    PPG_Plot = [];
                    ECG_Plot = [];
                    app.PPG = [];
                    app.ECG = [];
                    
                    while(strcmp(get(app.s,'Status'),'open'))
                        data = fgetl(app.s);
                        data = data(1:length(data));
                        
                        PTT=split(data,',');


                        Data_Count = Data_Count+1;
                        if length(PTT)>=7
                            HR_Valid = cellfun(@str2double, PTT(5));
                            SPO2_Valid = cellfun(@str2double, PTT(7));
                            if HR_Valid == 1 && SPO2_Valid == 1
                                app.Label_HR.Text = PTT(4);
                                app.Label_SPO2.Text = PTT(6);
%                                 app.Label_HighPressure.Text = PTT(7);
%                                 app.Label_LowPressure.Text = PTT(8);
                            end
                        end
                        
                      
                        if Data_Count<=1024
                            app.PPG(Data_Count) = -1 * cellfun(@str2double, PTT(2));
                            PPG_Plot = app.PPG(1:end);
                            app.ECG(Data_Count) = cellfun(@str2double, PTT(1));
                            ECG_Plot = app.ECG(1:end);
                        else
                            app.PPG(Data_Count) = -1 * cellfun(@str2double, PTT(2));
                            app.ECG(Data_Count) = cellfun(@str2double, PTT(1));
                            PPG_Plot = app.PPG(Data_Count-1023:end);
                            ECG_Plot = app.ECG(Data_Count-1023:end);
                        end
                        
                        if mod(Data_Count,4)==0
                            plot(app.UIAxes,PPG_Plot);
                            plot(app.UIAxes_2,ECG_Plot);
                            pause(1/(1024));
                        end
                        
                        if Data_Count >=512*3

                            fclose(app.s);
                            if strcmp(get(app.s,'Status'),'closed')
                                app.Lamp.Color = 'r';
                                app.Button_2.Text = "打开串口";
                                
                                app.Final_PPG = app.PPG(length(app.PPG)-1023:end);
                                app.Final_ECG = app.ECG(length(app.ECG)-1023:end); 
                                
                                app.PPG = [];
                                app.ECG = [];
                                
                                plot(app.UIAxes,app.Final_PPG);
                                plot(app.UIAxes_2,app.Final_ECG);
                                
                                [HeartRating,systolic_BP,diastolic_BP] = Pressure_Work(app);
                                app.Label_HighPressure.Text = num2str(round(systolic_BP));
                                app.Label_LowPressure.Text = num2str(round(diastolic_BP));
                                app.Label_HR.Text = num2str(round(HeartRating));
%                                 app.Label_HR.Text = num2str(round(HeartRating));
                                break;
                            end
                        end
                        
                     end
                 end
            else
                fclose(app.s);
                if strcmp(get(app.s,'Status'),'closed')
                    app.Lamp.Color = 'r';
                    app.Button_2.Text = "打开串口";
                    
                    app.Final_PPG = app.PPG(length(app.PPG)-1023:end);
                    app.Final_ECG = app.ECG(length(app.ECG)-1023:end); 
                    
                    plot(app.UIAxes,app.Final_PPG);
                    plot(app.UIAxes_2,app.Final_ECG);
                    
                    app.PPG = [];
                    app.ECG = [];
                    
                    [HeartRating,systolic_BP,diastolic_BP] = Pressure_Work(app);
                    app.Label_HighPressure.Text = num2str(round(systolic_BP));
                    app.Label_LowPressure.Text = num2str(round(diastolic_BP));
                    app.Label_HR.Text = num2str(round(HeartRating));
%                     app.Label_HR.Text = num2str(round(HeartRating));
                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            for i=1:length(app.DropDown.Items)
                if strcmp(value,app.DropDown.Items{i})
                   app.port_use = app.port(i);
                   app.s = serial(app.port_use,'BaudRate',115200,'Terminator','LF');
                   disp(i);
                   break;
                end
            end
        end

        % Callback function
        function ECGDropDownValueChanged(app, event)
            value = app.ECGDropDown.Value;
            for i=1:length(app.ECGDropDown.Items)
                if strcmp(value,app.ECGDropDown.Items{i})
                   app.port_use = app.port(i);
                   disp(i);
                   break;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '脉搏波')
            xlabel(app.UIAxes, {'t/times'; ''})
            ylabel(app.UIAxes, {'Value'; ''})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '心电')
            xlabel(app.UIAxes_2, {'t/times'; ''})
            ylabel(app.UIAxes_2, {'Value'; ''})
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.UIAxes_2.YTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.5'; '4'; '4.5'; '5'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '脉搏串口选择';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'COM3', 'COM4', 'COM5', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'COM3';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '搜索串口';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '打开串口';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '心率：';
            app.Label_HR = uilabel(app.UIFigure);
            app.Label_HR.Text = 'NULL';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '血氧：';
            app.Label_SPO2 = uilabel(app.UIFigure);
            app.Label_SPO2.Text = 'NULL';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '高压：';
            app.Label_HighPressure = uilabel(app.UIFigure);
            app.Label_HighPressure.Text = 'NULL';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '低压：';
            app.Label_LowPressure = uilabel(app.UIFigure);
            app.Label_LowPressure.Text = 'NULL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BloodPressure_Delay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end